classdef Matlab_innovative_Main_Code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhotoEditingAppUIFigure     matlab.ui.Figure
        NoiseFiltersButtonGroup     matlab.ui.container.ButtonGroup
        PeriodicNoiseButton         matlab.ui.control.Button
        SaltPaperNoiseButton        matlab.ui.control.Button
        GaussianNoiseButton         matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        BlueButton                  matlab.ui.control.Button
        GreenButton                 matlab.ui.control.Button
        RedButton                   matlab.ui.control.Button
        BasicFiltersButtonGroup     matlab.ui.container.ButtonGroup
        SmoothingFilterButton       matlab.ui.control.Button
        RGBtoGrayscaleButton        matlab.ui.control.Button
        BlackandWhiteButton         matlab.ui.control.Button
        NegativeImageButton         matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ImportanimageButton         matlab.ui.control.Button
        LogarithmicTransformButton  matlab.ui.control.Button
        BasicOperationsButtonGroup  matlab.ui.container.ButtonGroup
        DistanceButton              matlab.ui.control.Button
        CropButton                  matlab.ui.control.Button
        RotateButton                matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties
        inputImage  % Property to store the input image
        outputImage  % Property to store the output image
        grayImage
        rotatedImage
        redImage
        greenAndBlueZeros
        redChannel
        histogramData
        noisePattern
        saltProbability = 0.09; 
        pepperProbability = 0.09;
        smoothedImage
        originalXLabel
        originalYLabel
        restoredRGBImage
    end


    methods (Access = private)
        
        function startupFcn(app)
        % ... (your existing code)
    
        % Save original axes labels
        app.originalXLabel = app.UIAxes2.XLabel.String;
        app.originalYLabel = app.UIAxes2.YLabel.String;
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportanimageButton
        function upload_image(app, event)
            try
                [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp;*.gif;*.tif;*.jpeg', 'Image Files (*.jpg, *.png, *.bmp, *.gif, *.tif, *.jpeg)'}, 'Select an Image');
        
                if isequal(fileName, 0)  % User canceled the file selection
                    return;
                end
        
                % Load and display the selected image in the inputImageAxes
                imagePath = fullfile(filePath, fileName);
                app.inputImage = imread(imagePath);
        
                % Display the input image in UIAxes and force the update of the UI
                imshow(app.inputImage, 'Parent', app.UIAxes);
                drawnow;  % Force the update of the UI
            catch
                % Handle any errors that might occur during the file selection
                msgbox('An error occurred while loading the image.', 'Error', 'error');
            end
        end

        % Button pushed function: NegativeImageButton
        function applyNegativeFilterPushed(app, event)
            if isempty(app.inputImage)
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
                return;
            end
            
            % Apply the negative filter to the input image
            app.outputImage = 255 - app.inputImage;
            
            % Display the output image in the outputImageAxes
            imshow(app.outputImage, 'Parent', app.UIAxes2);
        end

        % Button pushed function: RGBtoGrayscaleButton
        function RGBtoGrey(app, event)
            if isempty(app.inputImage)
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
                return;

            else
                % Apply the gray level filter to the input image
                app.outputImage = im2gray(app.inputImage);
                
                % Display the output image in the outputImageAxes
                imshow(app.outputImage, 'Parent', app.UIAxes2);
            end
        end

        % Button pushed function: BlackandWhiteButton
        function RGBtoBW(app, event)
            if isempty(app.inputImage)
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
                return;
            end
            
            % Check if the input image is RGB
            if ndims(app.inputImage) == 3 && size(app.inputImage, 3) == 3
                % If it's an RGB image, convert it to grayscale
                app.grayImage = rgb2gray(app.inputImage);
            else
                % If it's not an RGB image, use it directly as a grayscale image
                app.grayImage = app.inputImage;
            end
            
            % Set the threshold value (adjust as needed)
            threshold = 0.5;
            
            % Convert the grayscale image to black and white using the threshold
            app.outputImage = imbinarize(app.grayImage, threshold);
            
            % Display the output image in the outputImageAxes
            imshow(app.outputImage, 'Parent', app.UIAxes2);

            
        end

        % Button pushed function: ResetButton
        function resetOutput(app, event)
            if ~isempty(app.inputImage)
                if size(app.inputImage, 3) == 3
                    % The input image was originally in RGB format
                    app.outputImage = app.inputImage;
                else
                    % The input image was grayscale-converted, restore it to RGB
                    [rows, cols] = size(app.inputImage);
                    app.outputImage = zeros(rows, cols, 3, 'like', app.inputImage);
                    app.outputImage(:, :, 1) = app.inputImage;
                    app.outputImage(:, :, 2) = app.inputImage;
                    app.outputImage(:, :, 3) = app.inputImage;
                end
        
                % Display the restored image in UIAxes2
                imshow(app.outputImage, 'Parent', app.UIAxes2);
            else
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
            end
            cla(app.UIAxes2);
        end

        % Button pushed function: ExitButton
        function exitFunc(app, event)
            % Close the main app figure window
            close(app.PhotoEditingAppUIFigure);
        end

        % Button pushed function: RotateButton
        function rotateFunc(app, event)
            if ~isempty(app.inputImage)
                % Rotate the input image by a specified angle (e.g., 90 degrees)

                app.rotatedImage = imrotate(app.inputImage, 90);

                % Display the rotated image in UIAxes2
                imshow(app.rotatedImage, 'Parent', app.UIAxes2);
            else
                msgbox('Please upload an image first.', 'Error', 'error');
            end
        end

        % Button pushed function: RedButton
        function redFunc(app, event)

% Button pushed function: GreenButton

            if size(app.inputImage, 3) == 3
                % The input image is already in RGB format
                app.outputImage = app.inputImage;
                app.outputImage(:, :, 2) = 0;
                app.outputImage(:, :, 3) = 0;
            else
                % Convert the grayscale input image to RGB
                [rows, cols] = size(app.inputImage);
                app.outputImage = zeros(rows, cols, 3, 'like', app.inputImage);
                app.outputImage(:, :, 1) = app.inputImage;
            end
        
            % Display the modified image in UIAxes2
            imshow(app.outputImage, 'Parent', app.UIAxes2);


        end

        % Button pushed function: DistanceButton
        function distanceFunc(app, event)

                % Display the output image in a new figure for measurement
                figure;
                imshow(app.inputImage);
            
                % Allow the user to click on two points to measure the distance
                [x1, y1] = ginput(1); % Get the first point
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2,x1, y1, 'ro', 'MarkerSize', 10);
                
                [x2, y2] = ginput(1); % Get the second point
                plot(app.UIAxes2,x2, y2, 'ro', 'MarkerSize', 10);
                
                % Calculate the Euclidean distance between the two points
                distance = sqrt((x2 - x1)^2 + (y2 - y1)^2);
            
                % Display the distance in a message box
                msg = sprintf('Distance between points: %.2f pixels', distance);
                msgbox(msg, 'Distance Measurement');
          
        end

        % Button pushed function: GreenButton
        function greenFunc(app, event)
            % if size(app.inputImage, 3) == 3
            %     % Extract the red channel\
            %     app.redImage=app.inputImage;
            %     app.redImage(:, :, 1)=0;
            %     app.redImage(:, :, 3)=0;
            % % Create an all-zero matrix for the green and blue channels
            % app.greenAndBlueZeros = zeros(size(app.redChannel), 'uint8');
            % 
            % % Create the output image by combining the red channel with zeroed green and blue channels
            % app.outputImage = cat(2, app.redChannel, app.greenAndBlueZeros, app.greenAndBlueZeros);
            % % Display the red component in UIAxes2
            % imshow(app.redImage, 'Parent', app.UIAxes2);
            % else
            %     % If the input image is not an RGB image, return an error message
            %     error('Input image is not an RGB image');
            % end
            % Button pushed function: GreenButton
            if size(app.inputImage, 3) == 3
                % The input image is already in RGB format
                app.outputImage = app.inputImage;
                app.outputImage(:, :, 1) = 0;
                app.outputImage(:, :, 3) = 0;
            else
                % Convert the grayscale input image to RGB
                [rows, cols] = size(app.inputImage);
                app.outputImage = zeros(rows, cols, 3, 'like', app.inputImage);
                app.outputImage(:, :, 2) = app.inputImage;
            end
        
            % Display the modified image in UIAxes2
            imshow(app.outputImage, 'Parent', app.UIAxes2);

        end

        % Button pushed function: BlueButton
        function blueFunc(app, event)
            if size(app.inputImage, 3) == 3
                % The input image is already in RGB format
                app.outputImage = app.inputImage;
                app.outputImage(:, :, 1) = 0;
                app.outputImage(:, :, 2) = 0;
            else
                % Convert the grayscale input image to RGB
                [rows, cols] = size(app.inputImage);
                app.outputImage = zeros(rows, cols, 3, 'like', app.inputImage);
                app.outputImage(:, :, 3) = app.inputImage;
            end
        
            % Display the modified image in UIAxes2
            imshow(app.outputImage, 'Parent', app.UIAxes2);
        end

        % Button pushed function: LogarithmicTransformButton
        function logT(app, event)
         
                app.grayImage = im2gray(app.inputImage);
                % Apply the logarithmic filter
                app.outputImage = 0.5 * log(1 + double(app.grayImage));
                
                % Ensure that pixel values are within the valid range [0, 255]
                app.outputImage = uint8(255 * (app.outputImage - min(app.outputImage(:))) / (max(app.outputImage(:)) - min(app.outputImage(:))));
                imshow(app.outputImage, 'Parent', app.UIAxes2);
       
        end

        % Callback function
        function histoFunc(app, event)
             
             if isempty(app.inputImage)
                 % Display an error message if there is no input image
                 msgbox('Please upload an image first.', 'Error', 'error');
                 return;
             end
             run('histo.m');
             % Plot the histogram in UIAxes2
             
    % % Check if the input image is in grayscale or RGB format
    %          if size(app.inputImage, 3) == 3
    %                 % Calculate the histogram for each color channel (Red, Green, Blue)
    %                 redHistogram = imhist(app.inputImage(:, :, 1));
    %                 greenHistogram = imhist(app.inputImage(:, :, 2));
    %                 blueHistogram = imhist(app.inputImage(:, :, 3));
    % 
    %                 % Plot the histograms in the specified UIAxes
    %                 bar(app.UIAxes2, redHistogram, 'r');
    %                 hold(app.UIAxes2, 'on');
    %                 bar(app.UIAxes2, greenHistogram, 'g');
    %                 bar(app.UIAxes2, blueHistogram, 'b');
    %                 hold(app.UIAxes2, 'off');
    % 
    %                 title(app.UIAxes2, 'Histogram of the RGB Image');
    % 
    %                 % Set labels for X and Y axes
    %                 xlabel(app.UIAxes2, 'Pixel Value');
    %                 ylabel(app.UIAxes2, 'Frequency');
    % 
    %                 % Optionally, add legends to differentiate the color channels
    %                 legend(app.UIAxes2, 'Red', 'Green', 'Blue');
    % 
    %         else
    %             % Grayscale image
    %             app.grayImage = app.inputImage;
    %             app.histogramData = imhist(app.grayImage);
    % 
    %              % Plot the histogram in UIAxes2
    %             bar(app.UIAxes2, app.histogramData, 'FaceColor', 'b');
    %             title(app.UIAxes2, 'Histogram of the Input Image');
    % 
    %                   % % Restore original labels
    %             app.UIAxes2.XLabel.String = app.originalXLabel;
    %             app.UIAxes2.YLabel.String = app.originalYLabel;
    % 
    %         end
    %         % Calculate the histogram
    %         app.histogramData = imhist(app.grayImage,256);
    % 
    %              % Plot the histogram in UIAxes2
    %         bar(app.UIAxes2, app.histogramData, 'FaceColor', 'b');
    %         title(app.UIAxes2, 'Histogram of the Input Image');
    % 
    %               % % Restore original labels
    %         app.UIAxes2.XLabel.String = app.originalXLabel;
    %         app.UIAxes2.YLabel.String = app.originalYLabel;
            
                  % 
              %Reset axes properties to maintain image size
             % app.UIAxes2.DataAspectRatio = [1 1 1];
             % app.UIAxes2.Position = [577 284 575 440];
            % if isempty(app.inputImage)
            %     % Display an error message if there is no input image
            %     msgbox('Please upload an image first.', 'Error', 'error');
            %     return;
            % end
            % 
            % % Calculate histograms for each RGB channel
            % redHistogram = imhist(app.inputImage(:,:,1));
            % greenHistogram = imhist(app.inputImage(:,:,2));
            % blueHistogram = imhist(app.inputImage(:,:,3));
            % 
            % % Plot the histograms in UIAxes2
            % bar(app.UIAxes2, redHistogram, 'r');
            % hold(app.UIAxes2, 'on');
            % bar(app.UIAxes2, greenHistogram, 'g');
            % bar(app.UIAxes2, blueHistogram, 'b');
            % hold(app.UIAxes2, 'off');
            % 
            % title(app.UIAxes2, 'Histogram of the Input Image');
            % xlabel(app.UIAxes2, 'Pixel Value');
            % ylabel(app.UIAxes2, 'Frequency');
            % 
            % % Optionally, you can add legends to differentiate the channels
            % legend(app.UIAxes2, 'Red', 'Green', 'Blue');


        end

        % Button pushed function: PeriodicNoiseButton
        function periodicFun(app, event)

                app.inputImage = im2gray(app.inputImage);
            
                [rows, cols] = size(app.inputImage);
                [X, ~] = meshgrid(1:cols, 1:rows);

                amplitude = 80; % Adjust as needed
                frequency = 0.02; % Adjust as needed
                
                % Create a sinusoidal pattern with the specified amplitude and frequency
                app.noisePattern = amplitude * sin(2 * pi * frequency * X);
                
                % Add the noise pattern to the input image
                app.outputImage = double(app.inputImage) + app.noisePattern;
                
                % Clip pixel values to the valid range [0, 255]
                app.outputImage(app.outputImage < 0) = 0;
                app.outputImage(app.outputImage > 255) = 255;
                imshow(app.outputImage, 'Parent', app.UIAxes2);
        end

        % Button pushed function: SaltPaperNoiseButton
        function saltnpepperFunc(app, event)

                app.inputImage = im2gray(app.inputImage);

                [rows, cols] = size(app.inputImage);
        
                % Create a random matrix with values between 0 and 1
                noiseMatrix = rand(rows, cols);
        
                % Set pixels to pure white (255) based on saltProbability
                app.outputImage = app.inputImage;
                app.outputImage(noiseMatrix < app.saltProbability) = 255;
        
                % Set pixels to pure black (0) based on pepperProbability
                app.outputImage(noiseMatrix > (1 - app.pepperProbability)) = 0;
        
                imshow(app.outputImage, 'Parent', app.UIAxes2);
        end

        % Button pushed function: GaussianNoiseButton
        function gaussianFunc(app, event)
           
            if ~isempty(app.inputImage)
                % Specify the mean (mean) and standard deviation (std) for Gaussian noise
                mean = 10;
                std = 100;  % You can adjust this value as needed
        
                % Generate Gaussian noise with the specified mean and standard deviation
                noise = uint8(std * randn(size(app.inputImage)) + mean);
        
                % Add the noise to the input image
                app.outputImage = imadd(app.inputImage, noise);
        
                % Clip pixel values to the valid range [0, 255]
                app.outputImage(app.outputImage < 0) = 0;
                app.outputImage(app.outputImage > 255) = 255;
        
                % Display the noisy image in UIAxes2
                imshow(app.outputImage, 'Parent', app.UIAxes2);
            else
                msgbox('Please upload an image first.', 'Error', 'error');
            end

        end

        % Button pushed function: SmoothingFilterButton
        function smoothingFunc(app, event)
          
            if isempty(app.inputImage)
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
                return;
            end
        
            if size(app.inputImage, 3) == 3
                % Convert the input image to grayscale
                app.grayImage = im2gray(app.inputImage);
            else
                app.grayImage = app.inputImage;
            end
        
            % Define the standard deviation (sigma) for the Gaussian filter
            sigma = 5; % You can adjust this value as needed
        
            % Apply Gaussian smoothing using imgaussfilt
            app.smoothedImage = imgaussfilt(app.grayImage, sigma);
        
            % Display the smoothed image in the outputImageAxes (UIAxes2)
            imshow(app.smoothedImage, 'Parent', app.UIAxes2);
        
        end

        % Button pushed function: CropButton
        function cropFunc(app, event)
            if isempty(app.inputImage)
                % Display an error message if there is no input image
                msgbox('Please upload an image first.', 'Error', 'error');
                return;
            end
        
            % Let the user interactively select a rectangular ROI
            croppedImage = imcrop(app.inputImage);
        
            % Display the cropped image in the outputImageAxes (UIAxes2)
            imshow(croppedImage, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhotoEditingAppUIFigure = uifigure('Visible', 'off');
            app.PhotoEditingAppUIFigure.Name = 'Photo Editing App';
            app.UIAxes = uiaxes(app.PhotoEditingAppUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            subtitle(app.UIAxes, 'INPUT')
            app.UIAxes2 = uiaxes(app.PhotoEditingAppUIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            subtitle(app.UIAxes2, 'OUTPUT')
            app.BasicOperationsButtonGroup = uibuttongroup(app.PhotoEditingAppUIFigure);
            app.BasicOperationsButtonGroup.Title = 'Basic Operations';
            app.RotateButton = uibutton(app.BasicOperationsButtonGroup, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateFunc, true);
            app.RotateButton.Text = 'Rotate';
            app.CropButton = uibutton(app.BasicOperationsButtonGroup, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @cropFunc, true);
            app.CropButton.Text = 'Crop';
            app.DistanceButton = uibutton(app.BasicOperationsButtonGroup, 'push');
            app.DistanceButton.ButtonPushedFcn = createCallbackFcn(app, @distanceFunc, true);
            app.DistanceButton.Text = 'Distance';
            app.LogarithmicTransformButton = uibutton(app.PhotoEditingAppUIFigure, 'push');
            app.LogarithmicTransformButton.ButtonPushedFcn = createCallbackFcn(app, @logT, true);
            app.LogarithmicTransformButton.Text = 'Logarithmic Transform';
            app.ImportanimageButton = uibutton(app.PhotoEditingAppUIFigure, 'push');
            app.ImportanimageButton.ButtonPushedFcn = createCallbackFcn(app, @upload_image, true);
            app.ImportanimageButton.Text = 'Import an image';
            app.ResetButton = uibutton(app.PhotoEditingAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetOutput, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.PhotoEditingAppUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exitFunc, true);
            app.ExitButton.Text = 'Exit';
            app.BasicFiltersButtonGroup = uibuttongroup(app.PhotoEditingAppUIFigure);
            app.BasicFiltersButtonGroup.Title = 'Basic Filters';
            app.NegativeImageButton = uibutton(app.BasicFiltersButtonGroup, 'push');
            app.NegativeImageButton.ButtonPushedFcn = createCallbackFcn(app, @applyNegativeFilterPushed, true);
            app.NegativeImageButton.Text = 'Negative Image';
            app.BlackandWhiteButton = uibutton(app.BasicFiltersButtonGroup, 'push');
            app.BlackandWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoBW, true);
            app.BlackandWhiteButton.Text = 'Black and White';
            app.RGBtoGrayscaleButton = uibutton(app.BasicFiltersButtonGroup, 'push');
            app.RGBtoGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGrey, true);
            app.RGBtoGrayscaleButton.Text = 'RGB to Grayscale';
            app.SmoothingFilterButton = uibutton(app.BasicFiltersButtonGroup, 'push');
            app.SmoothingFilterButton.ButtonPushedFcn = createCallbackFcn(app, @smoothingFunc, true);
            app.SmoothingFilterButton.Text = 'Smoothing Filter';
            app.ButtonGroup = uibuttongroup(app.PhotoEditingAppUIFigure);
            app.RedButton = uibutton(app.ButtonGroup, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @redFunc, true);
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.ButtonGroup, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @greenFunc, true);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.ButtonGroup, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @blueFunc, true);
            app.BlueButton.Text = 'Blue';
            app.NoiseFiltersButtonGroup = uibuttongroup(app.PhotoEditingAppUIFigure);
            app.NoiseFiltersButtonGroup.Title = 'Noise Filters';
            app.GaussianNoiseButton = uibutton(app.NoiseFiltersButtonGroup, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @gaussianFunc, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.SaltPaperNoiseButton = uibutton(app.NoiseFiltersButtonGroup, 'push');
            app.SaltPaperNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @saltnpepperFunc, true);
            app.SaltPaperNoiseButton.Text = 'Salt & Paper Noise';
            app.PeriodicNoiseButton = uibutton(app.NoiseFiltersButtonGroup, 'push');
            app.PeriodicNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @periodicFun, true);
            app.PeriodicNoiseButton.Text = 'Periodic Noise';
            % Show the figure after all components are created
            app.PhotoEditingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_innovative_Main_Code

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhotoEditingAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhotoEditingAppUIFigure)
        end
    end
end